classdef HCR_annotation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        RemovethiscellButton  matlab.ui.control.Button
        loadButton            matlab.ui.control.Button
        saveButton            matlab.ui.control.Button
        preCalcLabel          matlab.ui.control.Label
        preCalcSwitch         matlab.ui.control.Switch
        FailButton            matlab.ui.control.Button
        PassButton            matlab.ui.control.Button
        cellsLabel            matlab.ui.control.Label
        genesLabel            matlab.ui.control.Label
        zLabel                matlab.ui.control.Label
        zSlider               matlab.ui.control.Slider
        clim_pan              matlab.ui.container.Panel
        Snap25CheckBox        matlab.ui.control.CheckBox
        GFPCheckBox           matlab.ui.control.CheckBox
        Snap25MinSlider       matlab.ui.control.Slider
        Snap25MaxSlider       matlab.ui.control.Slider
        GFPMinSlider          matlab.ui.control.Slider
        GFPMaxSlider          matlab.ui.control.Slider
        Snap25label           matlab.ui.control.Label
        GFPlabel              matlab.ui.control.Label
        geneList              matlab.ui.control.ListBox
        cellList              matlab.ui.control.ListBox
        fullAx                matlab.ui.control.UIAxes
        zoomAx                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cent
        genes
        barcodes
        GFPchannel
        Snap25channel
        fn
        im
        clipsz
        parent
        ix
        pcol
        Ncells
        Nchans
        Nplanes
        ypix
        xpix
        selected
        curplane   
        zoomVis
        fullVis
        chanLabel_drop
        color_button
        cmin_edit
        cmax_edit
        autolim
        enable_checkbox
        rgb
        z_calc_vec % whether or not each z plane's rgb has been calculated
        figLims
        Snap25im
        Snap25rgb
    end
    
    methods (Access = private)
        
        function cellSelect(app)

            cellnum = get(app.cellList, 'Value');
            newStr = split(cellnum, ' ');
            cellnum = newStr{2};
            cellnum = str2double(cellnum);

            app.curplane = app.cent.z(cellnum);

            if app.z_calc_vec(app.curplane) == 0
                app.updateImage()
            else
                app.updateAxes()
            end

            app.zSlider.Value = app.curplane;
            app.zLabel.Text = strcat('Z-plane =  ', num2str(app.curplane));

        end
        
        function updateAxes(app)

            c = get(app.cellList, 'Value');
            newStr = split(c, ' ');
            c = newStr{2};
            c = str2double(c);

            set(app.zoomVis, 'CData', app.rgb(:, :, :, app.curplane));
            set(app.fullVis, 'CData', app.Snap25rgb(:, :, :, app.curplane));

            xlim(app.zoomAx, [app.ix.minx(c) app.ix.maxx(c)])
            ylim(app.zoomAx, [app.ix.miny(c) app.ix.maxy(c)])

            xlim(app.fullAx, [app.ix.minx(c) app.ix.maxx(c)])
            ylim(app.fullAx, [app.ix.miny(c) app.ix.maxy(c)])

        end
        
        function updateImage(app, ~, ~)

            if strcmp(app.preCalcSwitch.Value, 'On')

                app.rgb = zeros(app.ypix, app.xpix, 3, app.Nplanes);
                
                for i = 1:5
                    if ~get(app.enable_checkbox(i), 'Value')
                        continue;
                    end
                
                    clr = get(app.color_button(i), 'BackgroundColor');
                    scim = app.getScaledImage(get(app.chanLabel_drop(i), 'Value'), i, 'all');
                
                    for j = 1:3
                        app.rgb(:,:,j, :) = app.rgb(:,:,j, :)+scim.*clr(j);
                    end
                
                end
                
                app.updateAxes()

                app.z_calc_vec = ones(app.Nplanes, 1);

            else

                app.rgb(:,:,:, app.curplane) = zeros(app.ypix, app.xpix, 3, 1);

                for i = 1:5
                    if ~get(app.enable_checkbox(i), 'Value')
                        continue;
                    end
                
                    clr = get(app.color_button(i), 'BackgroundColor');
                    scim = app.getScaledImage(get(app.chanLabel_drop(i), 'Value'), i, app.curplane);
                
                    for j = 1:3
                        app.rgb(:,:,j, app.curplane) = app.rgb(:,:,j,app.curplane)+scim.*clr(j);
                    end
                
                end
                
                app.updateAxes()

                app.z_calc_vec(app.curplane) = 1;

            end
            
        end

        % function updateImageSnap(app, ~, ~)
        % 
        %     if strcmp(app.preCalcSwitch.Value, 'On')
        % 
        %         app.Snap25rgb = zeros(app.ypix, app.xpix, 3, app.Nplanes);
        % 
        %         if app.Snap25CheckBox.Value == 1
        % 
        %             clr = [0 0 1];
        % 
        %             scim = app.getScaledImage(app.Snap25channel, i, 'all');
        % 
        %             for j = 1:3
        %                 app.rgb(:,:,j, :) = app.rgb(:,:,j, :)+scim.*clr(j);
        %             end
        % 
        %         end
        % 
        %         for i = 1:5
        %             if ~get(app.enable_checkbox(i), 'Value')
        %                 continue;
        %             end
        % 
        %             clr = get(app.color_button(i), 'BackgroundColor');
        %             scim = app.getScaledImage(get(app.chanLabel_drop(i), 'Value'), i, 'all');
        % 
        %             for j = 1:3
        %                 app.rgb(:,:,j, :) = app.rgb(:,:,j, :)+scim.*clr(j);
        %             end
        % 
        %         end
        % 
        %         app.updateAxes()
        % 
        %         app.z_calc_vec = ones(app.Nplanes, 1);
        % 
        %     else
        % 
        %         app.rgb(:,:,:, app.curplane) = zeros(app.ypix, app.xpix, 3, 1);
        % 
        %         for i = 1:5
        %             if ~get(app.enable_checkbox(i), 'Value')
        %                 continue;
        %             end
        % 
        %             clr = get(app.color_button(i), 'BackgroundColor');
        %             scim = app.getScaledImage(get(app.chanLabel_drop(i), 'Value'), i, app.curplane);
        % 
        %             for j = 1:3
        %                 app.rgb(:,:,j, app.curplane) = app.rgb(:,:,j,app.curplane)+scim.*clr(j);
        %             end
        % 
        %         end
        % 
        %         app.updateAxes()
        % 
        %         app.z_calc_vec(app.curplane) = 1;
        % 
        %     end
        % 
        % end
        
        function colorButtonFcn(app, ~, ~, chan)

            newclr = uisetcolor(app.color_button(chan));
            set(app.color_button(chan), 'BackgroundColor', newclr);
            app.updateImage()
            
        end
        
        function enableLims(app, ~, ~, num)

            if (get(app.autolim(num), 'Value')==1)
                set(app.cmin_edit(num), 'Enable', 'Off');
                set(app.cmax_edit(num), 'Enable', 'Off');
            else
                set(app.cmin_edit(num), 'Enable', 'On');
                set(app.cmax_edit(num), 'Enable', 'On');
            end
            
            app.updateImage();
            app.z_calc_vec = zeros(app.Nplanes, 1);
            
        end
        
        function scaledIm = getScaledImage(app, truechan, cchan, plane)

            if (get(app.autolim(cchan), 'Value')==0)
                cmin = str2double(get(app.cmin_edit(cchan), 'String'));
                cmax = str2double(get(app.cmax_edit(cchan), 'String'));
                lims = [cmin cmax];
            else
                lims = app.figLims(truechan, :);
            end
            
            if strcmp(plane, 'all')
                scaledIm = (double(app.im.stack(truechan,:,:,:))- lims(1))./(lims(2)-lims(1));
                
                scaledIm(scaledIm>1) = 1;
                scaledIm(scaledIm<0) = 0;
            else
                scaledIm = (double(app.im.stack(truechan,:,:, plane))- lims(1))./(lims(2)-lims(1));

                scaledIm(scaledIm>1) = 1;
                scaledIm(scaledIm<0) = 0;

            end

            scaledIm = squeeze(scaledIm);
            
        end
        
        function lims = getFigLims(app, num)

            scaledIm = double(app.im.stack(num,:,:, :));

            a = qprctile(double(scaledIm(:)), [0.1 99.9]);
        
            cmin = a(1);
            cmax = a(2);
            
            % if cmax<=cmin
            %     cmax = cmin+1;
            % end
            % 
            % set(app.cmin_edit(num), 'String', num2str(round(cmin)));
            % set(app.cmax_edit(num), 'String', num2str(round(cmax)));

            
            lims = [cmin cmax];
            
        end
        
        function updateCellList(app)
            
            app.UIFigure.Pointer = 'watch';
            drawnow()

            gene_vec = app.barcodes.(app.geneList.Value);
            checked_vec = app.barcodes.(strcat(app.geneList.Value, '_checked'));
            
            for i = 1:length(app.cellList.Items)
                
                if checked_vec(i) == 1
                    if gene_vec(i) == 1
                        s = uistyle('Icon', '1200px-Green-checkmark.svg.png', FontColor='green');
                        addStyle(app.cellList, s, 'item', i)
                    else
                        s = uistyle('Icon', '1200px-Green-checkmark.svg.png', FontColor='red');
                        addStyle(app.cellList, s, 'item', i)
                    end
                else
                    s = uistyle('Icon', 'Red_X.svg.png', FontColor='black');
                    addStyle(app.cellList, s, 'item', i)

                end

            end

            app.UIFigure.Pointer = 'arrow';
            drawnow()

        end
        
        function updateImageReset(app, ~, ~)

            app.z_calc_vec = zeros(app.Nplanes, 1);
            app.updateImage()
            
        end
        
        function trueidx = getTrueChanIdx(app, dropdown_idx)

            localidx = get(app.chanLabel_drop(dropdown_idx), 'Value');

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
            
            app.UIFigure.Visible = 'off'; 

            addpath(fileparts(which(mfilename)))
            movegui(app.UIFigure, 'center')
            
            app.parent = 'E:\05-23_phox2b_retro_tracing\image_processing\slice_2\individual genes';

            % app.fn = {};
            % 
            % app.fn{end+1} = 'GFP_r1.tif';
            % app.fn{end+1} = 'Snap25_r1.tif';
            % app.fn{end+1} = 'Phox2b_r2.tif';
            
            app.fn = getFileNames(app.parent, 'tif');

            cent_array = readmatrix(strcat(app.parent, '\cell_centroids_s02'));
            cent_array = cent_array(2:end,2:4);
            app.cent.x = cent_array(:,3) + 1;
            app.cent.y = cent_array(:,2) + 1;
            app.cent.z = cent_array(:,1) + 1;
            app.cent.N = size(cent_array, 1);
            
            % extract gene names
            
            app.genes = {};
            
            for i = 1:length(app.fn)
            
                newStr = split(app.fn{i}, '.');
                app.genes{end+1} = newStr{1};
            
            end
            
            % generate barcoding table
            
            app.barcodes = table;
            
            for i = 1:length(app.genes)
            
                app.barcodes.(app.genes{i}) = zeros(app.cent.N, 1);
                app.barcodes.(strcat(app.genes{i}, '_checked')) = zeros(app.cent.N, 1);
            
            end
            
            % genes by X by Y by z-planes
            app.im.stack = loadTifStack(app.parent, app.fn);
            
            % find which channel is GFP and which channel is round 1 Snap25
            foundGFP = 0;
            foundSnap25 = 0;
            idx = 1;
            while (foundGFP == 0) || (foundSnap25 == 0) 
                if contains(app.genes{idx}, 'GFP')
                    app.GFPchannel = idx;
                    foundGFP = 1;
                    idx = idx + 1;
                elseif contains(app.genes{idx}, 'Snap25') && contains(app.genes{idx}, 'r1')
                    app.Snap25channel = idx;
                    foundSnap25 = 1;
                    idx = idx + 1;
                else
                    idx = idx + 1;
                end
            end
            
            app.clipsz = 101;

            % X x Y x cells x genes
            app.ix = getClips(app.im.stack, app.cent, app.clipsz);  

            app.Ncells = app.cent.N;
            app.Nchans = length(app.genes);
            app.Nplanes = size(app.im.stack, 4);

            app.z_calc_vec = zeros(app.Nplanes, 1);
            
            app.ypix = size(app.im.stack, 2);
            app.xpix = size(app.im.stack, 3);

            app.rgb = zeros(app.ypix, app.xpix, 3, app.Nplanes);
            
            app.selected = zeros(app.Ncells, app.Nchans);
            app.curplane = 1;

            axis(app.fullAx, 'image')
            axis(app.zoomAx, 'image')

            app.zSlider.MajorTicks = [1 app.Nplanes];
            app.zSlider.MajorTickLabels = {'1', num2str(app.Nplanes)};
            app.zSlider.MinorTicks = 1:app.Nplanes;
            app.zSlider.Limits = [1 app.Nplanes]; 

            app.zSlider.Value = app.curplane;
            app.zLabel.Text = strcat('Z-plane =  ', num2str(app.curplane));

            cellstr = cell(app.Ncells, 1);
            for i = 1:app.Ncells
                cellstr{i} = ['Cell ' num2str(i)];
            end

            app.cellList.Items = cellstr;
            app.geneList.Items = app.genes;

            possibleclrs = [...
                1 0 0;...
                0 1 0;...
                0 0 1;...
                1 0 1;...
                0 1 1;...
                1 1 0;...
                1 1 1;...
                1 0 0;...
                0 1 0;...
                0 0 1;...
                1 0 1;...
                0 1 1;...
                1 1 0;...
                1 1 1;...
                1 0 0;...
                0 1 0;...
                0 0 1;...
                1 0 1;...
                0 1 1;...
                1 1 0;...
                1 1 1];
            defclrs = possibleclrs(1:app.Nchans, :);


            app.pcol = [0.9 0.9 0.9];

            for i = 1:5
                cmax = 255;
                yoff = 30;
                xoff = 130*(i-1);
                
                app.chanLabel_drop(i) = uicontrol('parent', app.clim_pan, 'Style', 'popupmenu', 'String', app.genes, 'Units','pixels' ...
                    ,'BackgroundColor', [1 1 1],'Position',[xoff+15 yoff+150 110 20],'HorizontalAlignment','Center', 'Fontsize', 10, ...
                    'Value', i, 'Fontweight', 'Bold', 'Callback', {@app.updateImage});
                
                app.color_button(i) = uicontrol('parent', app.clim_pan,'Style', 'pushbutton',  'Units', 'Pixels', 'Position', ...
                [xoff+15, yoff+115, 110, 30], 'String', '', 'Callback', {@app.colorButtonFcn, i}, 'BackgroundColor', defclrs(i, :));
                
                uicontrol('parent', app.clim_pan, 'Style', 'text', 'String', 'Min: ','Units','pixels' ...
                    ,'BackgroundColor',app.pcol,'Position',[xoff+14 yoff+66 40 24],'HorizontalAlignment','Right');
                uicontrol('parent', app.clim_pan, 'Style', 'text', 'String', 'Max: ','Units','pixels' ...
                    ,'BackgroundColor',app.pcol,'Position',[xoff+14 yoff+37 40 24],'HorizontalAlignment','Right');
                
                app.cmin_edit(i) = uicontrol('parent', app.clim_pan, 'Style', 'edit', 'String', '0','Units','pixels' ...
                    ,'BackgroundColor',[1 1 1],'Position',[xoff+61 yoff+72 56 24],'HorizontalAlignment','Right', ...
                    'Callback', {@app.updateImageReset}, 'Enable', 'On');
                
                app.cmax_edit(i) = uicontrol('parent', app.clim_pan, 'Style', 'edit', 'String', num2str(cmax),'Units','pixels' ...
                    ,'BackgroundColor',[1 1 1],'Position',[xoff+61 yoff+42 56 24],'HorizontalAlignment','Right', ...
                    'Callback', {@app.updateImageReset}, 'Enable', 'On');
                
                app.autolim(i) = uicontrol('parent', app.clim_pan, 'Style', 'radiobutton',  'Units', 'pixels', 'Position', ...
                    [xoff+78 yoff+7 70 24], 'String', 'Auto','FontWeight','Bold', 'Min', 0, 'Max', 1, ...
                    'BackgroundColor', app.pcol, 'Value', 0, 'Callback', {@app.enableLims,i});
                
                app.enable_checkbox(i) = uicontrol('parent', app.clim_pan, 'Style', 'checkbox', 'Value', i<4,'Units','pixels' ...
                ,'BackgroundColor',app.pcol,'Position',[xoff+28 yoff+7 46 24],'HorizontalAlignment','Right', ...
                'Callback', {@app.updateImageReset}, 'BackgroundColor', app.pcol, 'String', 'On', 'FontSize', 10);
            
                uicontrol('parent', app.clim_pan, 'Style', 'text', 'String', ' ','Units','pixels' ...
                    ,'BackgroundColor',app.pcol-0.1,'Position',[xoff+132 yoff+10 5 150],'HorizontalAlignment','Right');
            end

            app.figLims = zeros(app.Nchans, 2);
            for i = 1:app.Nchans
                app.figLims(i,:) = app.getFigLims(i);
            end

            lims = app.figLims(app.Snap25channel, :);

            scaledIm = (double(app.im.stack(app.Snap25channel,:,:,:))- lims(1))./(lims(2)-lims(1));
            scaledIm(scaledIm>1) = 1;
            scaledIm(scaledIm<0) = 0;
            scaledIm = squeeze(scaledIm);

            app.Snap25rgb = zeros(app.ypix, app.xpix, 3, app.Nplanes);

            clr = [0 0 1];
            scim = scaledIm;
        
            for j = 1:3
                app.Snap25rgb(:,:,j,:) = squeeze(app.Snap25rgb(:,:,j,:))+scim.*clr(j);
            end

            lims = app.figLims(app.GFPchannel, :);

            scaledIm = (double(app.im.stack(app.GFPchannel,:,:,:))- lims(1))./(lims(2)-lims(1));
            scaledIm(scaledIm>1) = 1;
            scaledIm(scaledIm<0) = 0;
            scaledIm = squeeze(scaledIm);

            clr = [0 1 0];
            scim = scaledIm;

            for j = 1:3
                app.Snap25rgb(:,:,j,:) = squeeze(app.Snap25rgb(:,:,j,:))+scim.*clr(j);
            end

            app.fullVis = imagesc(app.fullAx, squeeze(app.Snap25rgb(:,:,:,1))); 
            colormap(app.fullAx, 'gray');

            app.zoomVis = imagesc(app.zoomAx, squeeze(app.Snap25rgb(:,:,:,1))); 
            colormap(app.zoomAx, 'gray');

            app.updateImage()
            app.updateCellList()

            app.UIFigure.Visible = 'on'; 
            
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)

            app.curplane = app.curplane - event.VerticalScrollCount;

            if app.curplane < 1
                app.curplane = 1;
            end

            if app.curplane > app.Nplanes
                app.curplane = app.Nplanes;
            end

            app.zSlider.Value = double(app.curplane);
            app.zLabel.Text = strcat('Z-plane = ', num2str(app.curplane));
            
            if app.z_calc_vec(app.curplane) == 0
                app.updateImage()
            else
                app.updateAxes()
            end
            
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)

            switch event.Key
                case 'downarrow'
                    cellnum = get(app.cellList, 'Value')+1;
                    if cellnum > app.Ncells
                        cellnum = app.Ncells;
                    end

                    set(app.cellList, 'Value', cellnum);
                    app.cellSelect()
            
                case 'uparrow'
                    cellnum = get(app.cellList, 'Value')-1;
                    if cellnum < 1
                        cellnum = 1;
                    end

                    set(app.cellList, 'Value', cellnum);
                    app.cellSelect()

                case 'z'

                    app.PassButtonPushed()

                case 'x'

                    app.FailButtonPushed()

                otherwise

                    return;
            end


        end

        % Value changed function: cellList
        function cellListValueChanged(app, event)
            app.cellSelect()
        end

        % Value changed function: geneList
        function geneListValueChanged(app, event)
            app.updateCellList()
        end

        % Value changed function: zSlider
        function zSliderValueChanged(app, event)
            
            app.zSlider.Value = round(app.zSlider.Value);
            app.curplane = int16(app.zSlider.Value);
            app.zLabel.Text = strcat('Z-plane =  ', num2str(app.curplane));

            if app.z_calc_vec(app.curplane) == 0
                app.updateImage()
            else
                app.updateAxes()
            end
            
            
        end

        % Button pushed function: PassButton
        function PassButtonPushed(app, event)
            c = get(app.cellList, 'Value');
            newStr = split(c, ' ');
            c = newStr{2};
            c = str2double(c);
            
            app.barcodes.(app.geneList.Value)(c) = 1;
            app.barcodes.(strcat(app.geneList.Value, '_checked'))(c) = 1;

            s = uistyle('Icon', '1200px-Green-checkmark.svg.png', FontColor='green');
            addStyle(app.cellList, s, 'item', c)

            if c < length(app.cellList.Items)
                app.cellList.Value = app.cellList.Items(c+1);
                scroll(app.cellList, app.cellList.Value)
            end

            app.cellSelect()
        end

        % Button pushed function: FailButton
        function FailButtonPushed(app, event)
            c = get(app.cellList, 'Value');
            newStr = split(c, ' ');
            c = newStr{2};
            c = str2double(c);
            
            app.barcodes.(app.geneList.Value)(c) = 0;
            app.barcodes.(strcat(app.geneList.Value, '_checked'))(c) = 1;

            s = uistyle('Icon', '1200px-Green-checkmark.svg.png', FontColor='red');
            addStyle(app.cellList, s, 'item', c)

            if c < length(app.cellList.Items)
                app.cellList.Value = app.cellList.Items(c+1);
                scroll(app.cellList, app.cellList.Value)
            end

            app.cellSelect()
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            writetable(app.barcodes, 'test.csv')
        end

        % Button pushed function: RemovethiscellButton
        function RemovethiscellButtonPushed(app, event)
            cellnum = get(app.cellList, 'Value');
            newStr = split(cellnum, ' ');
            cellnum = newStr{2};
            cellnum = str2double(cellnum);

            app.barcodes(cellnum,:) = [];
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            file = uigetfile('*.csv');
            app.barcodes = readtable(file);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.zoomAx = uiaxes(app.UIFigure);
            app.fullAx = uiaxes(app.UIFigure);
            app.cellList = uilistbox(app.UIFigure);
            app.cellList.ValueChangedFcn = createCallbackFcn(app, @cellListValueChanged, true);
            app.geneList = uilistbox(app.UIFigure);
            app.geneList.ValueChangedFcn = createCallbackFcn(app, @geneListValueChanged, true);
            app.clim_pan = uipanel(app.UIFigure);
            app.clim_pan.Title = 'Panel';
            app.GFPlabel = uilabel(app.clim_pan);
            app.GFPlabel.Text = 'GFP';
            app.Snap25label = uilabel(app.clim_pan);
            app.Snap25label.Text = 'Snap25';
            app.GFPMaxSlider = uislider(app.clim_pan);
            app.GFPMaxSlider.Value = 100;
            app.GFPMinSlider = uislider(app.clim_pan);
            app.Snap25MaxSlider = uislider(app.clim_pan);
            app.Snap25MaxSlider.Value = 100;
            app.Snap25MinSlider = uislider(app.clim_pan);
            app.GFPCheckBox = uicheckbox(app.clim_pan);
            app.GFPCheckBox.Text = 'On';
            app.Snap25CheckBox = uicheckbox(app.clim_pan);
            app.Snap25CheckBox.Text = 'On';
            app.zSlider = uislider(app.UIFigure);
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @zSliderValueChanged, true);
            app.zLabel = uilabel(app.UIFigure);
            app.genesLabel = uilabel(app.UIFigure);
            app.genesLabel.Text = 'Genes';
            app.cellsLabel = uilabel(app.UIFigure);
            app.cellsLabel.Text = 'Cells';
            app.PassButton = uibutton(app.UIFigure, 'push');
            app.PassButton.ButtonPushedFcn = createCallbackFcn(app, @PassButtonPushed, true);
            app.PassButton.Text = 'Pass (z)';
            app.FailButton = uibutton(app.UIFigure, 'push');
            app.FailButton.ButtonPushedFcn = createCallbackFcn(app, @FailButtonPushed, true);
            app.FailButton.Text = 'Fail (x)';
            app.preCalcSwitch = uiswitch(app.UIFigure, 'slider');
            app.preCalcLabel = uilabel(app.UIFigure);
            app.preCalcLabel.Text = 'Precalculate all z-planes?';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Icon = fullfile(pathToMLAPP, 'save-file.svg');
            app.saveButton.Text = '';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Icon = fullfile(pathToMLAPP, '216-2166612_load-svg-clip-arts-load-file-icon-png.png');
            app.loadButton.Text = '';
            app.RemovethiscellButton = uibutton(app.UIFigure, 'push');
            app.RemovethiscellButton.ButtonPushedFcn = createCallbackFcn(app, @RemovethiscellButtonPushed, true);
            app.RemovethiscellButton.Text = 'Remove this cell';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HCR_annotation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end